classdef PREPROCESS_MULTIFIB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CONVERT_panel                matlab.ui.container.Panel
        locate_cxd                   matlab.ui.control.Button
        path_cxd                     matlab.ui.control.EditField
        convert                      matlab.ui.control.Button
        status_conversion            matlab.ui.control.TextArea
        maxfilesizeGBEditFieldLabel  matlab.ui.control.Label
        maxGB                        matlab.ui.control.NumericEditField
        MOTIONCORRECT_panel          matlab.ui.container.Panel
        locate_tif                   matlab.ui.control.Button
        path_tif                     matlab.ui.control.EditField
        run_motion_correct           matlab.ui.control.Button
        status_motion_correction     matlab.ui.control.TextArea
        maxshiftpixelsLabel          matlab.ui.control.Label
        max_shift                    matlab.ui.control.NumericEditField
        cropmarginpixelsLabel        matlab.ui.control.Label
        crop_margin                  matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        % update status text field
        function update_status(app, status_text_field, update_str)
            app.(status_text_field).Value = update_str;
            pause(0.5)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on'; 
            pause(1)
        end

        % Button pushed function: locate_cxd
        function locate_cxdButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.cxd');
            app.path_cxd.Value = fullfile(filepath,filename);
        end

        % Value changed function: path_cxd
        function path_cxdValueChanged(app, event)
            value = app.path_cxd.Value;            
        end

        % Value changed function: maxGB
        function maxGBValueChanged(app, event)
            value = app.maxGB.Value;
            
        end

        % Button pushed function: convert
        function convertButtonPushed(app, event)
            update_status(app,'status_conversion','converting now ... ')
            cxd2tif(app.path_cxd.Value,'maxSizeGB',app.maxGB.Value);
            update_status(app,'status_conversion','Finished saving .tif file(s).')
        end

        % Button pushed function: locate_tif
        function locate_tifButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.tif');
            app.path_tif.Value = fullfile(filepath,filename);
        end

        % Value changed function: path_tif
        function path_tifValueChanged(app, event)
            value = app.path_tif.Value;            
        end

        % Button pushed function: run_motion_correct
        function run_motion_correctButtonPushed(app, event)
            update_status(app,'status_motion_correction','motion correcting now ... ')
            outfile = [app.path_tif.Value(1:end-4) '_MC.tif'];
            tifInfo = imfinfo(app.path_tif.Value);
            tifHeight = tifInfo(1).Height;
            tifWidth = tifInfo(1).Width;
            crop_points = round([app.crop_margin.Value app.crop_margin.Value,...
                tifWidth-app.crop_margin.Value tifHeight-app.crop_margin.Value]);
            motion_correct(app.path_tif.Value,'outfile',outfile,...
                'crop_points',crop_points,...
                'maxshift_x',app.max_shift.Value,...
                'maxshift_y',app.max_shift.Value);       
            update_status(app,'status_motion_correction',['saved: ' app.path_tif.Value(1:end-4) '_MC.tif'])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CONVERT_panel = uipanel(app.UIFigure);
            app.CONVERT_panel.Title = 'CONVERT .cxd to .tif';
            app.locate_cxd = uibutton(app.CONVERT_panel, 'push');
            app.locate_cxd.ButtonPushedFcn = createCallbackFcn(app, @locate_cxdButtonPushed, true);
            app.locate_cxd.Text = 'Locate .cxd';
            app.path_cxd = uieditfield(app.CONVERT_panel, 'text');
            app.path_cxd.ValueChangedFcn = createCallbackFcn(app, @path_cxdValueChanged, true);
            app.convert = uibutton(app.CONVERT_panel, 'push');
            app.convert.ButtonPushedFcn = createCallbackFcn(app, @convertButtonPushed, true);
            app.convert.Text = 'CONVERT';
            app.status_conversion = uitextarea(app.CONVERT_panel);
            app.status_conversion.Editable = 'off';
            app.maxfilesizeGBEditFieldLabel = uilabel(app.CONVERT_panel);
            app.maxfilesizeGBEditFieldLabel.Text = 'max file size (GB)';
            app.maxGB = uieditfield(app.CONVERT_panel, 'numeric');
            app.maxGB.ValueChangedFcn = createCallbackFcn(app, @maxGBValueChanged, true);
            app.maxGB.Value = 15;
            app.MOTIONCORRECT_panel = uipanel(app.UIFigure);
            app.MOTIONCORRECT_panel.Title = 'MOTION CORRECT .tif';
            app.locate_tif = uibutton(app.MOTIONCORRECT_panel, 'push');
            app.locate_tif.ButtonPushedFcn = createCallbackFcn(app, @locate_tifButtonPushed, true);
            app.locate_tif.Text = 'Locate .tif';
            app.path_tif = uieditfield(app.MOTIONCORRECT_panel, 'text');
            app.path_tif.ValueChangedFcn = createCallbackFcn(app, @path_tifValueChanged, true);
            app.run_motion_correct = uibutton(app.MOTIONCORRECT_panel, 'push');
            app.run_motion_correct.ButtonPushedFcn = createCallbackFcn(app, @run_motion_correctButtonPushed, true);
            app.run_motion_correct.Text = 'MOTION CORRECT';
            app.status_motion_correction = uitextarea(app.MOTIONCORRECT_panel);
            app.status_motion_correction.Editable = 'off';
            app.maxshiftpixelsLabel = uilabel(app.MOTIONCORRECT_panel);
            app.maxshiftpixelsLabel.Text = 'max shift (pixels)';
            app.max_shift = uieditfield(app.MOTIONCORRECT_panel, 'numeric');
            app.max_shift.Value = 30;
            app.cropmarginpixelsLabel = uilabel(app.MOTIONCORRECT_panel);
            app.cropmarginpixelsLabel.Text = '"crop" margin (pixels) ';
            app.crop_margin = uieditfield(app.MOTIONCORRECT_panel, 'numeric');
            app.crop_margin.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PREPROCESS_MULTIFIB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end